classdef OptiSat_Dashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        STATUSTextArea        matlab.ui.control.TextArea
        STATUSTextAreaLabel   matlab.ui.control.Label
        RunAutoDenoiseButton  matlab.ui.control.Button
        UploadImageButton     matlab.ui.control.Button
        UIAxes3               matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        WorkingImage % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            % 1. Open a file browser to let the user select an image
            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg', 'Image Files'});

            % If the user cancels or closes the window, do nothing
            if isequal(file, 0)
                return; 
            end

            % 2. Load the image into the app's memory
            img_path = fullfile(path, file);
            raw_img = imread(img_path);

            % Ensure it is grayscale and double precision
            if size(raw_img, 3) == 3
                raw_img = rgb2gray(raw_img);
            end
            app.WorkingImage = im2double(raw_img);

            % 3. Display it on the first (left) axis
            imshow(app.WorkingImage, 'Parent', app.UIAxes);
        end

        % Button pushed function: RunAutoDenoiseButton
        function RunAutoDenoiseButtonPushed(app, event)
            % 1. Grab image
            img = app.WorkingImage;
            if isempty(img)
                app.STATUSTextArea.Value = 'ERROR: Please upload an image first!';
                return; 
            end
            app.STATUSTextArea.Value = 'STATUS: Generating Heavy Degradation...';
            drawnow;

            % 2. HEAVY CORRUPTION (Refusing to compromise on noise)
            noisy = imnoise(img, 'gaussian', 0,0.01);
            noisy(1:15:end, :) = noisy(1:15:end, :) + 0.4;
            noisy = min(noisy, 1);
            imshow(noisy, 'Parent', app.UIAxes2);

            % 3. STAGE 1: GAUSSIAN NOTCH FILTER (Stripe Removal)
            app.STATUSTextArea.Value = 'STATUS: Running DSP Notch Filter...';
            drawnow;
            F = fft2(noisy);
            Fshifted = fftshift(F);
            [rows, cols] = size(noisy);
            crow = round(rows/2); ccol = round(cols/2);
            [u, v] = meshgrid(1:cols, 1:rows);
            D_u = abs(u - ccol);
            D0_notch = 3; 
            H_notch = 1 - exp(-(D_u.^2) / (2 * (D0_notch^2)));
            H_notch(crow-10:crow+10, ccol-10:ccol+10) = 1;

            destriped = real(ifft2(ifftshift(Fshifted .* H_notch)));
            destriped = max(min(destriped, 1), 0);

            % 4. STAGE 2: AI BASE MAP (Deep Learning)
            app.STATUSTextArea.Value = 'STATUS: Booting AI Base Pass...';
            drawnow;
            net = denoisingNetwork('DnCNN');
            ai_base = denoiseImage(destriped, net);

            % 5. STAGE 3: ECE GUIDED FUSION (The Breakthrough)
            app.STATUSTextArea.Value = 'STATUS: Executing Edge-Guided Fusion...';
            drawnow;
            % Use the AI image to guide the sharp extraction from the raw data
            final_img = imguidedfilter(destriped, ai_base, 'NeighborhoodSize', [5 5], 'DegreeOfSmoothing', 0.005);

            % 6. METRICS & DISPLAY
            imshow(final_img, 'Parent', app.UIAxes3);
            psnr_val = psnr(final_img, img);
            ssim_val = ssim(final_img, img);

            telemetry_text = sprintf('STATUS: SUCCESS\nNoise Var: 0.01 (Heavy)\nPSNR: %.2f dB\nSSIM: %.4f', psnr_val, ssim_val);
            app.STATUSTextArea.Value = telemetry_text;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'bone');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ORIGINAL IMAGE')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            colormap(app.UIAxes, 'bone')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'SIMULATING NOISE (AWGN+STRIPE NOISE)')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            colormap(app.UIAxes2, 'bone')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'DENOISED IMAGE')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            colormap(app.UIAxes3, 'bone')
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.RunAutoDenoiseButton = uibutton(app.UIFigure, 'push');
            app.RunAutoDenoiseButton.ButtonPushedFcn = createCallbackFcn(app, @RunAutoDenoiseButtonPushed, true);
            app.RunAutoDenoiseButton.Text = 'Run Auto-Denoise';
            app.STATUSTextAreaLabel = uilabel(app.UIFigure);
            app.STATUSTextAreaLabel.Text = 'STATUS';
            app.STATUSTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptiSat_Dashboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end